classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        WhattypeofSimulationdoyouwanttodoDropDown  matlab.ui.control.DropDown
        WhattypeofSimulationdoyouwanttodoDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WhattypeofSimulationdoyouwanttodoDropDown
        function WhattypeofSimulationdoyouwanttodoDropDownValueChanged(app, event)
            value = app.WhattypeofSimulationdoyouwanttodoDropDown.Value;
            if value == "Straightline simulation"
                straightline
                app.UIFigure.Visible = "off";
            elseif value == "Yaw simulation"
                yaw
                app.UIFigure.Visible = "off";
            elseif value == "Cornering Simulation"
                cornering
                app.UIFigure.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhattypeofSimulationdoyouwanttodoDropDownLabel = uilabel(app.UIFigure);
            app.WhattypeofSimulationdoyouwanttodoDropDownLabel.Text = 'What type of Simulation do you want to do?';
            app.WhattypeofSimulationdoyouwanttodoDropDown = uidropdown(app.UIFigure);
            app.WhattypeofSimulationdoyouwanttodoDropDown.Items = {'<Select here>', 'Straightline simulation', 'Yaw simulation', 'Cornering simulation'};
            app.WhattypeofSimulationdoyouwanttodoDropDown.ValueChangedFcn = createCallbackFcn(app, @WhattypeofSimulationdoyouwanttodoDropDownValueChanged, true);
            app.WhattypeofSimulationdoyouwanttodoDropDown.Value = '<Select here>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end