classdef yaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YawSimulationSetupLabel         matlab.ui.control.Label
        Label32                         matlab.ui.control.Label
        zdirectionLabel                 matlab.ui.control.Label
        Label31                         matlab.ui.control.Label
        ydirectionLabel                 matlab.ui.control.Label
        Label30                         matlab.ui.control.Label
        xdirectionLabel                 matlab.ui.control.Label
        groundLabel                     matlab.ui.control.Label
        Label29                         matlab.ui.control.Label
        zcomponentofflowdirectionLabel  matlab.ui.control.Label
        Label28                         matlab.ui.control.Label
        ycomponentofflowdirectionLabel  matlab.ui.control.Label
        Label27                         matlab.ui.control.Label
        xcomponentofflowdirectionLabel  matlab.ui.control.Label
        Label26                         matlab.ui.control.Label
        MassFlowRatekgsLabel            matlab.ui.control.Label
        diffuserinletLabel              matlab.ui.control.Label
        aeromappingLabel                matlab.ui.control.Label
        steeringangleEditField          matlab.ui.control.NumericEditField
        steeringangleEditFieldLabel     matlab.ui.control.Label
        Label25                         matlab.ui.control.Label
        Label24                         matlab.ui.control.Label
        Label23                         matlab.ui.control.Label
        zmLabel_8                       matlab.ui.control.Label
        ymLabel_8                       matlab.ui.control.Label
        xmLabel_8                       matlab.ui.control.Label
        RotationAxisDirectionLabel_4    matlab.ui.control.Label
        Label22                         matlab.ui.control.Label
        Label21                         matlab.ui.control.Label
        Label20                         matlab.ui.control.Label
        zmLabel_7                       matlab.ui.control.Label
        ymLabel_7                       matlab.ui.control.Label
        xmLabel_7                       matlab.ui.control.Label
        RotationAxisOriginLabel_4       matlab.ui.control.Label
        RearwheelrightLabel             matlab.ui.control.Label
        Label19                         matlab.ui.control.Label
        Label18                         matlab.ui.control.Label
        Label17                         matlab.ui.control.Label
        zmLabel_6                       matlab.ui.control.Label
        ymLabel_6                       matlab.ui.control.Label
        xmLabel_6                       matlab.ui.control.Label
        RotationAxisDirectionLabel_3    matlab.ui.control.Label
        Label16                         matlab.ui.control.Label
        Label15                         matlab.ui.control.Label
        Label14                         matlab.ui.control.Label
        zmLabel_5                       matlab.ui.control.Label
        ymLabel_5                       matlab.ui.control.Label
        xmLabel_5                       matlab.ui.control.Label
        RotationAxisOriginLabel_3       matlab.ui.control.Label
        RearwheelleftLabel              matlab.ui.control.Label
        carvelocitymsEditField          matlab.ui.control.NumericEditField
        carvelocitymsEditFieldLabel     matlab.ui.control.Label
        yawangleEditField               matlab.ui.control.NumericEditField
        yawangleEditFieldLabel          matlab.ui.control.Label
        CarDropDown                     matlab.ui.control.DropDown
        CarDropDownLabel                matlab.ui.control.Label
        Label13                         matlab.ui.control.Label
        Label12                         matlab.ui.control.Label
        Label11                         matlab.ui.control.Label
        zmLabel_4                       matlab.ui.control.Label
        ymLabel_4                       matlab.ui.control.Label
        xmLabel_4                       matlab.ui.control.Label
        RotationAxisDirectionLabel_2    matlab.ui.control.Label
        Label10                         matlab.ui.control.Label
        Label9                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        zmLabel_3                       matlab.ui.control.Label
        ymLabel_3                       matlab.ui.control.Label
        xmLabel_3                       matlab.ui.control.Label
        RotationAxisOriginLabel_2       matlab.ui.control.Label
        FrontwheelrightLabel            matlab.ui.control.Label
        Label7                          matlab.ui.control.Label
        Label6                          matlab.ui.control.Label
        Label5                          matlab.ui.control.Label
        zmLabel_2                       matlab.ui.control.Label
        ymLabel_2                       matlab.ui.control.Label
        xmLabel_2                       matlab.ui.control.Label
        RotationAxisDirectionLabel      matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        zmLabel                         matlab.ui.control.Label
        ymLabel                         matlab.ui.control.Label
        xmLabel                         matlab.ui.control.Label
        BacktomainmenuButton            matlab.ui.control.Button
        RotationAxisOriginLabel         matlab.ui.control.Label
        FrontwheelleftLabel             matlab.ui.control.Label
        WheelsLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WheelangularspeedradsLabel      matlab.ui.control.Label
        calculatesetupButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculatesetupButton
        function calculatesetupButtonPushed(app, event)
            
            velocity = app.carvelocitymsEditField.Value;
            
            %wheel rotation calculation
            if app.carvelocitymsEditField.Value ~= 0
                app.Label.Visible = "on";
                rotational_velocity = round(-velocity/0.235,2);
                app.Label.Text = string(rotational_velocity);
            end
            
            if app.CarDropDown.Value ~= "<select car>"

                %output Labels activation
                app.Label2.Visible = "on";
                app.Label3.Visible = "on";
                app.Label4.Visible = "on";
                app.Label5.Visible = "on";
                app.Label6.Visible = "on";
                app.Label7.Visible = "on";
                app.Label8.Visible = "on";
                app.Label9.Visible = "on";
                app.Label10.Visible = "on";
                app.Label11.Visible = "on";
                app.Label12.Visible = "on";
                app.Label13.Visible = "on";
                app.Label14.Visible = "on";
                app.Label15.Visible = "on";
                app.Label16.Visible = "on";
                app.Label17.Visible = "on";
                app.Label18.Visible = "on";
                app.Label19.Visible = "on";
                app.Label20.Visible = "on";
                app.Label21.Visible = "on";
                app.Label22.Visible = "on";
                app.Label23.Visible = "on";
                app.Label24.Visible = "on";
                app.Label25.Visible = "on";
                app.Label26.Visible = "on";
                app.Label27.Visible = "on";
                app.Label28.Visible = "on";
                app.Label29.Visible = "on";
                app.Label30.Visible = "on";
                app.Label31.Visible = "on";
                app.Label32.Visible = "on";


                %Wheelbase lookup
                if app.CarDropDown.Value == "TY 20"
                    wheelbase = 1.580;
                elseif app.CarDropDown.Value == "TY 22"
                    wheelbase = 1.530;
                end

                distance_origin_fw = 0.47;
                distance_ground_CenterOfWheel = 0.1723;
                distance_origin_rw = distance_origin_fw + wheelbase;
                distance_symmetry_fw = 0.625;
                distance_symmetry_rw = 0.6;
            
                %Front Wheel left rotation outputs
                app.Label2.Text = num2str(distance_origin_fw);                                                              % x origin
                app.Label3.Text = num2str(-distance_symmetry_fw);                                                           % y origin
                app.Label4.Text = num2str(distance_ground_CenterOfWheel);                                                   % z origin

                app.Label5.Text = num2str(round(sind(app.yawangleEditField.Value - app.steeringangleEditField.Value),4));   % x direction
                app.Label6.Text = num2str(round(cosd(app.yawangleEditField.Value - app.steeringangleEditField.Value),4));   % y direction
                app.Label7.Text = "0";                                                                                      % z direction
            
                %Front Wheel right rotation outputs
                app.Label8.Text = num2str(distance_origin_fw);                                                              % x origin
                app.Label9.Text = num2str(distance_symmetry_fw);                                                            % y origin
                app.Label10.Text = num2str(distance_ground_CenterOfWheel);                                                  % z origin

                app.Label11.Text = num2str(round(sind(app.yawangleEditField.Value - app.steeringangleEditField.Value),4));  % x direction
                app.Label12.Text = num2str(round(cosd(app.yawangleEditField.Value - app.steeringangleEditField.Value),4));  % y direction
                app.Label13.Text = "0";                                                                                     % z direction

                %Rear Wheel left rotation outputs
                app.Label14.Text = num2str(distance_origin_rw);                                                             % x origin
                app.Label15.Text = num2str(-distance_symmetry_rw);                                                          % y origin
                app.Label16.Text = num2str(distance_ground_CenterOfWheel);                                                  % z origin

                app.Label17.Text = num2str(round(sind(app.yawangleEditField.Value),4));                                     % x direction
                app.Label18.Text = num2str(round(cosd(app.yawangleEditField.Value),4));                                     % y direction
                app.Label19.Text = "0";                                                                                     % z direction
            
                %Rear Wheel right rotation outputs                
                app.Label20.Text = num2str(distance_origin_rw);                                                             % x origin
                app.Label21.Text = num2str(distance_symmetry_rw);                                                           % y origin
                app.Label22.Text = num2str(distance_ground_CenterOfWheel);                                                  % z origin

                app.Label23.Text = num2str(round(sind(app.yawangleEditField.Value),4));                                     % x direction
                app.Label24.Text = num2str(round(cosd(app.yawangleEditField.Value),4));                                     % y direction
                app.Label25.Text = "0";                                                                                     % z direction

                %Diffuser Inlet calculations
                massflow_cooler = 0.544;
                app.Label26.Text = num2str(massflow_cooler);
                diffuser_angle = 33;
                app.Label27.Text = num2str(round(cosd(diffuser_angle),4));                  % x direction
                app.Label28.Text = num2str(round(sind(-app.yawangleEditField.Value),4));     % y direction
                app.Label29.Text = num2str(round(sind(diffuser_angle),4));                  % z direction

                %Ground direction calculations
                app.Label30.Text = num2str(round(cosd(app.yawangleEditField.Value),4));     % x direction
                app.Label31.Text = num2str(round(sind(app.yawangleEditField.Value),4));     % y direction
                app.Label32.Text = "0";                                                     % z direction

            end
        end

        % Button pushed function: BacktomainmenuButton
        function BacktomainmenuButtonPushed(app, event)
            interface
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.calculatesetupButton = uibutton(app.UIFigure, 'push');
            app.calculatesetupButton.ButtonPushedFcn = createCallbackFcn(app, @calculatesetupButtonPushed, true);
            app.calculatesetupButton.Text = 'calculate setup';
            app.WheelangularspeedradsLabel = uilabel(app.UIFigure);
            app.WheelangularspeedradsLabel.Text = 'Wheel angular speed [rad/s]:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.WheelsLabel = uilabel(app.UIFigure);
            app.WheelsLabel.Text = 'Wheels';
            app.FrontwheelleftLabel = uilabel(app.UIFigure);
            app.FrontwheelleftLabel.Text = 'Frontwheel left';
            app.RotationAxisOriginLabel = uilabel(app.UIFigure);
            app.RotationAxisOriginLabel.Text = 'Rotation-Axis Origin';
            app.BacktomainmenuButton = uibutton(app.UIFigure, 'push');
            app.BacktomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomainmenuButtonPushed, true);
            app.BacktomainmenuButton.Text = 'Back to main menu';
            app.xmLabel = uilabel(app.UIFigure);
            app.xmLabel.Text = 'x [m]:';
            app.ymLabel = uilabel(app.UIFigure);
            app.ymLabel.Text = 'y [m]:';
            app.zmLabel = uilabel(app.UIFigure);
            app.zmLabel.Text = 'z [m]:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Visible = 'off';
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Visible = 'off';
            app.Label4.Text = 'Label4';
            app.RotationAxisDirectionLabel = uilabel(app.UIFigure);
            app.RotationAxisDirectionLabel.Text = 'Rotation-Axis Direction';
            app.xmLabel_2 = uilabel(app.UIFigure);
            app.xmLabel_2.Text = 'x [m]:';
            app.ymLabel_2 = uilabel(app.UIFigure);
            app.ymLabel_2.Text = 'y [m]:';
            app.zmLabel_2 = uilabel(app.UIFigure);
            app.zmLabel_2.Text = 'z [m]:';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Visible = 'off';
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Visible = 'off';
            app.Label6.Text = 'Label6';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Visible = 'off';
            app.Label7.Text = 'Label7';
            app.FrontwheelrightLabel = uilabel(app.UIFigure);
            app.FrontwheelrightLabel.Text = 'Frontwheel right';
            app.RotationAxisOriginLabel_2 = uilabel(app.UIFigure);
            app.RotationAxisOriginLabel_2.Text = 'Rotation-Axis Origin';
            app.xmLabel_3 = uilabel(app.UIFigure);
            app.xmLabel_3.Text = 'x [m]:';
            app.ymLabel_3 = uilabel(app.UIFigure);
            app.ymLabel_3.Text = 'y [m]:';
            app.zmLabel_3 = uilabel(app.UIFigure);
            app.zmLabel_3.Text = 'z [m]:';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Visible = 'off';
            app.Label8.Text = 'Label8';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Visible = 'off';
            app.Label9.Text = 'Label9';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Visible = 'off';
            app.Label10.Text = 'Label10';
            app.RotationAxisDirectionLabel_2 = uilabel(app.UIFigure);
            app.RotationAxisDirectionLabel_2.Text = 'Rotation-Axis Direction';
            app.xmLabel_4 = uilabel(app.UIFigure);
            app.xmLabel_4.Text = 'x [m]:';
            app.ymLabel_4 = uilabel(app.UIFigure);
            app.ymLabel_4.Text = 'y [m]:';
            app.zmLabel_4 = uilabel(app.UIFigure);
            app.zmLabel_4.Text = 'z [m]:';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Visible = 'off';
            app.Label11.Text = 'Label11';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Visible = 'off';
            app.Label12.Text = 'Label12';
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Visible = 'off';
            app.Label13.Text = 'Label13';
            app.CarDropDownLabel = uilabel(app.UIFigure);
            app.CarDropDownLabel.Text = 'Car:';
            app.CarDropDown = uidropdown(app.UIFigure);
            app.CarDropDown.Items = {'<select car>', 'TY 20', 'TY 22'};
            app.CarDropDown.Value = '<select car>';
            app.yawangleEditFieldLabel = uilabel(app.UIFigure);
            app.yawangleEditFieldLabel.Text = 'yaw angle [°]:';
            app.yawangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.carvelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.carvelocitymsEditFieldLabel.Text = 'car velocity [m/s]:';
            app.carvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RearwheelleftLabel = uilabel(app.UIFigure);
            app.RearwheelleftLabel.Text = 'Rearwheel left';
            app.RotationAxisOriginLabel_3 = uilabel(app.UIFigure);
            app.RotationAxisOriginLabel_3.Text = 'Rotation-Axis Origin';
            app.xmLabel_5 = uilabel(app.UIFigure);
            app.xmLabel_5.Text = 'x [m]:';
            app.ymLabel_5 = uilabel(app.UIFigure);
            app.ymLabel_5.Text = 'y [m]:';
            app.zmLabel_5 = uilabel(app.UIFigure);
            app.zmLabel_5.Text = 'z [m]:';
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Visible = 'off';
            app.Label14.Text = 'Label14';
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Visible = 'off';
            app.Label15.Text = 'Label15';
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Visible = 'off';
            app.Label16.Text = 'Label16';
            app.RotationAxisDirectionLabel_3 = uilabel(app.UIFigure);
            app.RotationAxisDirectionLabel_3.Text = 'Rotation-Axis Direction';
            app.xmLabel_6 = uilabel(app.UIFigure);
            app.xmLabel_6.Text = 'x [m]:';
            app.ymLabel_6 = uilabel(app.UIFigure);
            app.ymLabel_6.Text = 'y [m]:';
            app.zmLabel_6 = uilabel(app.UIFigure);
            app.zmLabel_6.Text = 'z [m]:';
            app.Label17 = uilabel(app.UIFigure);
            app.Label17.Visible = 'off';
            app.Label17.Text = 'Label17';
            app.Label18 = uilabel(app.UIFigure);
            app.Label18.Visible = 'off';
            app.Label18.Text = 'Label18';
            app.Label19 = uilabel(app.UIFigure);
            app.Label19.Visible = 'off';
            app.Label19.Text = 'Label19';
            app.RearwheelrightLabel = uilabel(app.UIFigure);
            app.RearwheelrightLabel.Text = 'Rearwheel right';
            app.RotationAxisOriginLabel_4 = uilabel(app.UIFigure);
            app.RotationAxisOriginLabel_4.Text = 'Rotation-Axis Origin';
            app.xmLabel_7 = uilabel(app.UIFigure);
            app.xmLabel_7.Text = 'x [m]:';
            app.ymLabel_7 = uilabel(app.UIFigure);
            app.ymLabel_7.Text = 'y [m]:';
            app.zmLabel_7 = uilabel(app.UIFigure);
            app.zmLabel_7.Text = 'z [m]:';
            app.Label20 = uilabel(app.UIFigure);
            app.Label20.Visible = 'off';
            app.Label20.Text = 'Label20';
            app.Label21 = uilabel(app.UIFigure);
            app.Label21.Visible = 'off';
            app.Label21.Text = 'Label21';
            app.Label22 = uilabel(app.UIFigure);
            app.Label22.Visible = 'off';
            app.Label22.Text = 'Label22';
            app.RotationAxisDirectionLabel_4 = uilabel(app.UIFigure);
            app.RotationAxisDirectionLabel_4.Text = 'Rotation-Axis Direction';
            app.xmLabel_8 = uilabel(app.UIFigure);
            app.xmLabel_8.Text = 'x [m]:';
            app.ymLabel_8 = uilabel(app.UIFigure);
            app.ymLabel_8.Text = 'y [m]:';
            app.zmLabel_8 = uilabel(app.UIFigure);
            app.zmLabel_8.Text = 'z [m]:';
            app.Label23 = uilabel(app.UIFigure);
            app.Label23.Visible = 'off';
            app.Label23.Text = 'Label23';
            app.Label24 = uilabel(app.UIFigure);
            app.Label24.Visible = 'off';
            app.Label24.Text = 'Label24';
            app.Label25 = uilabel(app.UIFigure);
            app.Label25.Visible = 'off';
            app.Label25.Text = 'Label25';
            app.steeringangleEditFieldLabel = uilabel(app.UIFigure);
            app.steeringangleEditFieldLabel.Text = 'steering angle [°]:';
            app.steeringangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.aeromappingLabel = uilabel(app.UIFigure);
            app.aeromappingLabel.Text = '(aero mapping)';
            app.diffuserinletLabel = uilabel(app.UIFigure);
            app.diffuserinletLabel.Text = 'diffuser inlet';
            app.MassFlowRatekgsLabel = uilabel(app.UIFigure);
            app.MassFlowRatekgsLabel.Text = 'Mass Flow Rate [kg/s]:';
            app.Label26 = uilabel(app.UIFigure);
            app.Label26.Visible = 'off';
            app.Label26.Text = 'Label26';
            app.xcomponentofflowdirectionLabel = uilabel(app.UIFigure);
            app.xcomponentofflowdirectionLabel.Text = 'x-component of flow direction:';
            app.Label27 = uilabel(app.UIFigure);
            app.Label27.Visible = 'off';
            app.Label27.Text = 'Label27';
            app.ycomponentofflowdirectionLabel = uilabel(app.UIFigure);
            app.ycomponentofflowdirectionLabel.Text = 'y-component of flow direction:';
            app.Label28 = uilabel(app.UIFigure);
            app.Label28.Visible = 'off';
            app.Label28.Text = 'Label28';
            app.zcomponentofflowdirectionLabel = uilabel(app.UIFigure);
            app.zcomponentofflowdirectionLabel.Text = 'z-component of flow direction:';
            app.Label29 = uilabel(app.UIFigure);
            app.Label29.Visible = 'off';
            app.Label29.Text = 'Label29';
            app.groundLabel = uilabel(app.UIFigure);
            app.groundLabel.Text = 'ground';
            app.xdirectionLabel = uilabel(app.UIFigure);
            app.xdirectionLabel.Text = 'x-direction:';
            app.Label30 = uilabel(app.UIFigure);
            app.Label30.Visible = 'off';
            app.Label30.Text = 'Label30';
            app.ydirectionLabel = uilabel(app.UIFigure);
            app.ydirectionLabel.Text = 'y-direction:';
            app.Label31 = uilabel(app.UIFigure);
            app.Label31.Visible = 'off';
            app.Label31.Text = 'Label31';
            app.zdirectionLabel = uilabel(app.UIFigure);
            app.zdirectionLabel.Text = 'z-direction:';
            app.Label32 = uilabel(app.UIFigure);
            app.Label32.Visible = 'off';
            app.Label32.Text = 'Label32';
            app.YawSimulationSetupLabel = uilabel(app.UIFigure);
            app.YawSimulationSetupLabel.Text = 'Yaw Simulation Setup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yaw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end